classdef Process_PrairieData_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        LoadxmlButton              matlab.ui.control.Button
        LaserTextAreaLabel         matlab.ui.control.Label
        LaserTextArea              matlab.ui.control.TextArea
        PMTGainsTextAreaLabel      matlab.ui.control.Label
        PMTGainsTextArea           matlab.ui.control.TextArea
        ImagingTextAreaLabel       matlab.ui.control.Label
        ImagingTextArea            matlab.ui.control.TextArea
        SequenceTextAreaLabel      matlab.ui.control.Label
        SequenceTextArea           matlab.ui.control.TextArea
        ExperimentParametersLabel  matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        CreateVolumesandVideosImagesLabel  matlab.ui.control.Label
        RedButtonGroup             matlab.ui.container.ButtonGroup
        None_R                     matlab.ui.control.RadioButton
        Ch1_R                      matlab.ui.control.RadioButton
        Ch2_R                      matlab.ui.control.RadioButton
        Ch3_R                      matlab.ui.control.RadioButton
        Ch4_R                      matlab.ui.control.RadioButton
        GreenButtonGroup           matlab.ui.container.ButtonGroup
        None_G                     matlab.ui.control.RadioButton
        Ch1_G                      matlab.ui.control.RadioButton
        Ch2_G                      matlab.ui.control.RadioButton
        Ch3_G                      matlab.ui.control.RadioButton
        Ch4_G                      matlab.ui.control.RadioButton
        BlueButtonGroup            matlab.ui.container.ButtonGroup
        None_B                     matlab.ui.control.RadioButton
        Ch1_B                      matlab.ui.control.RadioButton
        Ch2_B                      matlab.ui.control.RadioButton
        Ch3_B                      matlab.ui.control.RadioButton
        Ch4_B                      matlab.ui.control.RadioButton
        ColorCheckBox              matlab.ui.control.CheckBox
        BWCheckBox                 matlab.ui.control.CheckBox
        BWButtonGroup              matlab.ui.container.ButtonGroup
        Ch1_BW                     matlab.ui.control.RadioButton
        Ch2_BW                     matlab.ui.control.RadioButton
        Ch3_BW                     matlab.ui.control.RadioButton
        Ch4_BW                     matlab.ui.control.RadioButton
        SaveCreateButton           matlab.ui.control.Button
        Test                       matlab.ui.control.EditField
        RawmatfileCheckBox         matlab.ui.control.CheckBox
        RawtifffileCheckBox        matlab.ui.control.CheckBox
        VideosImageCheckBox        matlab.ui.control.CheckBox
        SavingoptionsLabel         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Parameters; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {466, 466};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {307, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadxmlButton
        function LoadxmlButtonPushed(app, event)
            
            [xml_file,path] = uigetfile('*.xml');
            app.Parameters = Func_ExtractParameters(xml_file,path);
            fid = fopen([app.Parameters.FolderPath app.Parameters.FolderName '.txt'],'w');
            LaserData{1} = ['Wavelength = ' sprintf('%i',app.Parameters.LaserWavelength) ' nm'];
            LaserData{2} = ['Power = ' sprintf('%i',round(app.Parameters.LaserPower)) ' mW'];
            app.LaserTextArea.Value = LaserData;
            for ii =1:2
                fprintf(fid,['Laser ' LaserData{ii} '\n']);
            end
            clear LaserData;
            
            PMTData{1} = ['1 [MA] = ' sprintf('%i',app.Parameters.PMT_Gain(1))];
            PMTData{2} = ['2 [GaAsP] = ' sprintf('%i',app.Parameters.PMT_Gain(2))];
            PMTData{3} = ['3 [GaAsP] = ' sprintf('%i',app.Parameters.PMT_Gain(3))];
            PMTData{4} = ['4 [MA] = ' sprintf('%i',app.Parameters.PMT_Gain(4))];
            app.PMTGainsTextArea.Value = PMTData;
             for ii =1:4
                fprintf(fid,['PMT Gain ' PMTData{ii} '\n']);
            end
           clear PMTData;
            
            ImagingData{1} = ['Objective: ' sprintf('%i',app.Parameters.Objective) 'x'];
            X = round(app.Parameters.FOVpixels(1)*app.Parameters.PixelSize(1));
            Y = round(app.Parameters.FOVpixels(2)*app.Parameters.PixelSize(2));
            ImagingData{2} = ['FOV: ' sprintf('%i',X) 'um x ' sprintf('%i',Y) 'um'];
            X = app.Parameters.FOVpixels(1);
            Y = app.Parameters.FOVpixels(2);
            ImagingData{3} = ['Image Size: ' sprintf('%i',X) ' x ' sprintf('%i',Y)];
            X = app.Parameters.PixelSize(1);
            Y = app.Parameters.PixelSize(2);
            ImagingData{4} = ['Pixel Size: ' sprintf('%.2f',X) 'um x ' sprintf('%.2f',Y) 'um'];
            app.ImagingTextArea.Value = ImagingData;
             for ii =1:4
                fprintf(fid,[ImagingData{ii} '\n']);
             end            
            clear ImagingData;
            
            SequenceData{1} = app.Parameters.Sequence;
            SequenceData{2} = [sprintf('%i',app.Parameters.NbImages) ' image(s)'];
            
            %             ch = find(app.Parameters.PMT_Gain~=0);
            if isequal(app.Parameters.Sequence,'TSeries')
                SequenceData{3} = ['Frame Period: ' sprintf('%i',round(100*app.Parameters.FramePeriod)) ' ms'];
            else
                if isequal(app.Parameters.Sequence,'ZSeries')
                    dz = abs(app.Parameters.ZSeries.Position(3,2) - app.Parameters.ZSeries.Position(3,1));
                    SequenceData{3} = ['z-step = ' sprintf('%.2f',dz) ' um'];
                end
            end
            app.SequenceTextArea.Value = SequenceData;
             for ii =1:length(SequenceData)
                fprintf(fid,[SequenceData{ii} '\n']);
             end            
            clear ch SequenceData;
            fclose(fid);
            
            %% Enabling/Disabling Channels for Image/Videos based on Channel used during experiment
            if app.Parameters.PMT_Gain(1)==0
                app.Ch1_R.Enable=0;
                app.Ch1_G.Enable=0;
                app.Ch1_B.Enable=0;
                app.Ch1_BW.Enable=0;
            else
                app.Ch1_R.Enable=1;
                app.Ch1_G.Enable=1;
                app.Ch1_B.Enable=1;
                app.Ch1_BW.Enable=1;
            end

            
            if app.Parameters.PMT_Gain(2)==0
                app.Ch2_R.Enable=0;
                app.Ch2_G.Enable=0;
                app.Ch2_B.Enable=0;
                app.Ch2_BW.Enable=0;
            else
                app.Ch2_R.Enable=1;
                app.Ch2_G.Enable=1;
                app.Ch2_B.Enable=1;
                app.Ch2_BW.Enable=1;
            end
            
            if app.Parameters.PMT_Gain(3)==0
                app.Ch3_R.Enable=0;
                app.Ch3_G.Enable=0;
                app.Ch3_B.Enable=0;
                app.Ch3_BW.Enable=0;
            else
                app.Ch3_R.Enable=1;
                app.Ch3_G.Enable=1;
                app.Ch3_B.Enable=1;
                app.Ch3_BW.Enable=1;
            end
            
            if app.Parameters.PMT_Gain(4)==0
                app.Ch4_R.Enable=0;
                app.Ch4_G.Enable=0;
                app.Ch4_B.Enable=0;
                app.Ch4_BW.Enable=0;
            else
                app.Ch4_R.Enable=1;
                app.Ch4_G.Enable=1;
                app.Ch4_B.Enable=1;
                app.Ch4_BW.Enable=1;
            end

        end

        % Button pushed function: SaveCreateButton
        function SaveCreateButtonPushed(app, event)
            
            app.Test.Value = '';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% if the users want a RGB image/video of his data %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if (app.ColorCheckBox.Value ==0 && app.BWCheckBox.Value== 0)
                app.Test.Value = 'Choose Color or BW output';
            end

            if (app.RawmatfileCheckBox.Value ==0 && app.RawtifffileCheckBox.Value == 0 && app.VideosImageCheckBox.Value ==0)
                app.Test.Value = 'Choose output format(s)';
            end

            if (app.ColorCheckBox.Value)
                % Initializing volume
                X = app.Parameters.FOVpixels(1);
                Y = app.Parameters.FOVpixels(2);
                Z = app.Parameters.NbImages;
                I = zeros(Y,X,Z,3);
                
                % Defining the channels to use for RGB
                %%% Red Channel %%%
                if (app.Ch1_R.Value)
                    R = 1;
                else
                    if (app.Ch2_R.Value)
                        R = 2;
                    else
                        if (app.Ch3_R.Value)
                            R = 3;
                        else
                            if (app.Ch4_R.Value)
                                R = 4;
                            else
                                R = 0;
                            end
                        end
                    end
                end
                
                %%% Green Channel %%%
                if (app.Ch1_G.Value)
                    G = 1;
                else
                    if (app.Ch2_G.Value)
                        G = 2;
                    else
                        if (app.Ch3_G.Value)
                            G = 3;
                        else
                            if (app.Ch4_G.Value)
                                G = 4;
                            else
                                G = 0;
                            end
                        end
                    end
                end
                
                %%% Blue Channel %%%
                if (app.Ch1_B.Value)
                    B = 1;
                else
                    if (app.Ch2_B.Value)
                        B = 2;
                    else
                        if (app.Ch3_B.Value)
                            B = 3;
                        else
                            if (app.Ch4_B.Value)
                                B = 4;
                            else
                                B = 0;
                            end
                        end
                    end
                end
                
                % Reconstruct the volume
                for ii = 1:app.Parameters.NbImages
                    if R~=0
                        I(:,:,ii,1) = imread([app.Parameters.FolderPath char(app.Parameters.Channel{R}.name(:,ii))]);
                    end
                    if G~=0
                        I(:,:,ii,2) = imread([app.Parameters.FolderPath char(app.Parameters.Channel{G}.name(:,ii))]);
                    end
                    if B~=0
                        I(:,:,ii,3) = imread([app.Parameters.FolderPath char(app.Parameters.Channel{B}.name(:,ii))]);
                    end
                end
                
                % Saving the volume as .mat file his box checked
                if (app.RawmatfileCheckBox.Value)
                    save([app.Parameters.FolderPath app.Parameters.FolderName '_RGB_rawdata.mat'],"I");
                    app.Test.Value = 'Done';
                end
                % Saving the volume as .tiff file his box checked
                if (app.RawtifffileCheckBox.Value)
                    imwrite(bitshift(uint16(squeeze(I(:,:,1,:))),4), [app.Parameters.FolderPath app.Parameters.FolderName '_RGB_rawdata.tiff']);
                    if app.Parameters.NbImages>1
                        for ii = 2:app.Parameters.NbImages
                            imwrite(bitshift(uint16(squeeze(I(:,:,ii,:))),4), [app.Parameters.FolderPath app.Parameters.FolderName '_RGB_rawdata.tiff'],'WriteMode','append');                
                        end
                    end
                    app.Test.Value = 'Done';
                end
                
                
                 % Saving the volume as Video/images his box checked
               if (app.VideosImageCheckBox.Value)
               if app.Parameters.NbImages == 1 % Saving 1 images if Sequence is 'Single'
                    outfile = [app.Parameters.FolderPath app.Parameters.FolderName '_RGB.tiff'];
                    if app.Parameters.PixelSize(1) == app.Parameters.PixelSize(2) % for isotropic pixel size
                        imwrite(mat2gray(squeeze(I)),outfile);
                    else % for anisotropic pixel size
                        fig = figure('Visible', 'off');
                        set(fig, 'Position', [0 0 700 700]);
                        imagesc([0 app.Parameters.FOVpixels(2)*app.Parameters.PixelSize(2)], ...
                            [0 app.Parameters.FOVpixels(1)*app.Parameters.PixelSize(1)],squeeze(mat2gray(I)));
                        axis equal; axis off;
                        frame = getframe(gcf);
                        f = double(rgb2gray(frame.cdata));
                        tab = std(f,[],1);
                        f(:,tab==0)=[];
                        frame.cdata(:,tab==0,:)=[];
                        tab = std(f,[],2);
                        frame.cdata(tab==0,:,:)=[];
                        imwrite(frame.cdata,outfile);
                        clear frame fig f tab;
                    end
                else % Saving as video if Sequence is 'TSeries' or 'ZSeries'
                    outfile = [app.Parameters.FolderPath app.Parameters.FolderName '_RGB.avi'];
                    writerObj = VideoWriter(outfile);
                    writerObj.FrameRate = 1/app.Parameters.FramePeriod;
                    open(writerObj);
                    if app.Parameters.PixelSize(1) == app.Parameters.PixelSize(2) % for isotropic pixel size
                        for ii = 1:app.Parameters.NbImages
                            writeVideo(writerObj,squeeze(mat2gray(I(:,:,ii,:))));
                        end
                    else % for anisotropic pixel size
                        fig = figure('Visible', 'off');
                        set(fig, 'Position', [0 0 700 700]);
                        for ii = 1:app.Parameters.NbImages
                            imagesc([0 app.Parameters.FOVpixels(2)*app.Parameters.PixelSize(2)], ...
                                [0 app.Parameters.FOVpixels(1)*app.Parameters.PixelSize(1)],squeeze(mat2gray(I(:,:,ii,:))));
                            axis equal; axis off;
                            frame = getframe(gcf);
                            f = double(rgb2gray(frame.cdata));
                            tab = std(f,[],1);
                            f(:,tab==0)=[];
                            frame.cdata(:,tab==0,:)=[];
                            tab = std(f,[],2);
                            frame.cdata(tab==0,:,:)=[];
                            writeVideo(writerObj,frame);
                        end
                        clear frame fig f tab;
                    end
                    
                    close(writerObj)
                    app.Test.Value = 'Done';
                end
               end
                
                
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% if the users want a BW image/video of his data %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if (app.BWCheckBox.Value)
                X = app.Parameters.FOVpixels(1);
                Y = app.Parameters.FOVpixels(2);
                Z = app.Parameters.NbImages;
                I = zeros(Y,X,Z);
                
                % Defining the channel
                if (app.Ch1_BW.Value)
                    BW = 1;
                else
                    if (app.Ch2_BW.Value)
                        BW = 2;
                    else
                        if (app.Ch3_BW.Value)
                            BW = 3;
                        else
                            if (app.Ch4_BW.Value)
                                BW = 4;
                            end
                        end
                    end
                end
                
                % Reconstruct the volume
                for ii = 1:app.Parameters.NbImages
                    I(:,:,ii) = imread([app.Parameters.FolderPath char(app.Parameters.Channel{BW}.name(:,ii))]);
                end
                
                % Saving the volume as .mat file his box checked
                if (app.RawmatfileCheckBox.Value)
                    save([app.Parameters.FolderPath app.Parameters.FolderName '_BW.mat'],"I");
                    app.Test.Value = 'Done';
                end
                
                if app.Parameters.NbImages == 1 % Saving 1 images if Sequence is 'Single'
                    outfile = [app.Parameters.FolderPath app.Parameters.FolderName '_BW.tiff'];
                    if app.Parameters.PixelSize(1) == app.Parameters.PixelSize(2) % for isotropic pixel size
                        imwrite(mat2gray(I),outfile);
                    else % for anisotropic pixel size
                        fig = figure('Visible', 'off');
                        set(fig, 'Position', [0 0 700 700]);
                        imagesc([0 app.Parameters.FOVpixels(2)*app.Parameters.PixelSize(2)], ...
                            [0 app.Parameters.FOVpixels(1)*app.Parameters.PixelSize(1)],mat2gray(I));
                        axis equal; axis off;colormap gray;
                        frame = getframe(gcf);
                        f = double(rgb2gray(frame.cdata));
                        tab = std(f,[],1);
                        f(:,tab==0)=[];
                        frame.cdata(:,tab==0,:)=[];
                        tab = std(f,[],2);
                        frame.cdata(tab==0,:,:)=[];
                        imwrite(frame.cdata,outfile);
                        clear frame fig f tab;
                    end
                    app.Test.Value = 'Done';
                else % Saving as video if Sequence is 'TSeries' or 'ZSeries'
                    outfile = [app.Parameters.FolderPath app.Parameters.FolderName '_BW.avi'];
                    writerObj = VideoWriter(outfile);
                    writerObj.FrameRate = 1/app.Parameters.FramePeriod;
                    open(writerObj);
                    if app.Parameters.PixelSize(1) == app.Parameters.PixelSize(2) % for isotropic pixel size
                        for ii = 1:app.Parameters.NbImages
                            writeVideo(writerObj,mat2gray(I(:,:,ii)));
                        end
                    else % for anisotropic pixel size
                        fig = figure('Visible', 'off');
                        set(fig, 'Position', [0 0 700 700]);
                        for ii = 1:app.Parameters.NbImages
                            imagesc([0 app.Parameters.FOVpixels(2)*app.Parameters.PixelSize(2)], ...
                                [0 app.Parameters.FOVpixels(1)*app.Parameters.PixelSize(1)],mat2gray(I(:,:,ii)));
                            axis equal; axis off;colormap gray;
                            frame = getframe(gcf);
                            f = double(rgb2gray(frame.cdata));
                            tab = std(f,[],1);
                            f(:,tab==0)=[];
                            frame.cdata(:,tab==0,:)=[];
                            tab = std(f,[],2);
                            frame.cdata(tab==0,:,:)=[];
                            writeVideo(writerObj,frame);
                        end
                        clear frame fig f tab;
                    end
                    
                    close(writerObj)
                    app.Test.Value = 'Done';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadxmlButton = uibutton(app.LeftPanel, 'push');
            app.LoadxmlButton.ButtonPushedFcn = createCallbackFcn(app, @LoadxmlButtonPushed, true);
            app.LoadxmlButton.Text = 'Load .xml file and save parameters as .mat';
            app.LaserTextAreaLabel = uilabel(app.LeftPanel);
            app.LaserTextAreaLabel.Text = 'Laser';
            app.LaserTextArea = uitextarea(app.LeftPanel);
            app.LaserTextArea.Value = {'Wavelength:'; 'Power: '};
            app.PMTGainsTextAreaLabel = uilabel(app.LeftPanel);
            app.PMTGainsTextAreaLabel.Text = 'PMT Gains';
            app.PMTGainsTextArea = uitextarea(app.LeftPanel);
            app.PMTGainsTextArea.Value = {'1 [MA] : '; '2 [GaAsP] : '; '3 [GaAsP] : '; '4 [MA] : '};
            app.ImagingTextAreaLabel = uilabel(app.LeftPanel);
            app.ImagingTextAreaLabel.Text = 'Imaging';
            app.ImagingTextArea = uitextarea(app.LeftPanel);
            app.ImagingTextArea.Value = {'Objective: '; 'FOV: '; 'Image Size: '; 'Pixel Size: '};
            app.SequenceTextAreaLabel = uilabel(app.LeftPanel);
            app.SequenceTextAreaLabel.Text = 'Sequence';
            app.SequenceTextArea = uitextarea(app.LeftPanel);
            app.SequenceTextArea.Value = {''; ''};
            app.ExperimentParametersLabel = uilabel(app.LeftPanel);
            app.ExperimentParametersLabel.Text = 'Experiment Parameters';
            app.RightPanel = uipanel(app.GridLayout);
            app.CreateVolumesandVideosImagesLabel = uilabel(app.RightPanel);
            app.CreateVolumesandVideosImagesLabel.Text = 'Create Volumes and Videos/Images';
            app.RedButtonGroup = uibuttongroup(app.RightPanel);
            app.RedButtonGroup.Title = 'Red';
            app.None_R = uiradiobutton(app.RedButtonGroup);
            app.None_R.Text = 'None';
            app.None_R.Value = true;
            app.Ch1_R = uiradiobutton(app.RedButtonGroup);
            app.Ch1_R.Text = '1 [MA]';
            app.Ch2_R = uiradiobutton(app.RedButtonGroup);
            app.Ch2_R.Text = '2 [GaAsP]';
            app.Ch3_R = uiradiobutton(app.RedButtonGroup);
            app.Ch3_R.Text = '3 [GaAsP]';
            app.Ch4_R = uiradiobutton(app.RedButtonGroup);
            app.Ch4_R.Text = '4 [MA]';
            app.GreenButtonGroup = uibuttongroup(app.RightPanel);
            app.GreenButtonGroup.Title = 'Green';
            app.None_G = uiradiobutton(app.GreenButtonGroup);
            app.None_G.Text = 'None';
            app.None_G.Value = true;
            app.Ch1_G = uiradiobutton(app.GreenButtonGroup);
            app.Ch1_G.Text = '1 [MA]';
            app.Ch2_G = uiradiobutton(app.GreenButtonGroup);
            app.Ch2_G.Text = '2 [GaAsP]';
            app.Ch3_G = uiradiobutton(app.GreenButtonGroup);
            app.Ch3_G.Text = '3 [GaAsP]';
            app.Ch4_G = uiradiobutton(app.GreenButtonGroup);
            app.Ch4_G.Text = '4 [MA]';
            app.BlueButtonGroup = uibuttongroup(app.RightPanel);
            app.BlueButtonGroup.Title = 'Blue';
            app.None_B = uiradiobutton(app.BlueButtonGroup);
            app.None_B.Text = 'None';
            app.None_B.Value = true;
            app.Ch1_B = uiradiobutton(app.BlueButtonGroup);
            app.Ch1_B.Text = '1 [MA]';
            app.Ch2_B = uiradiobutton(app.BlueButtonGroup);
            app.Ch2_B.Text = '2 [GaAsP]';
            app.Ch3_B = uiradiobutton(app.BlueButtonGroup);
            app.Ch3_B.Text = '3 [GaAsP]';
            app.Ch4_B = uiradiobutton(app.BlueButtonGroup);
            app.Ch4_B.Text = '4 [MA]';
            app.ColorCheckBox = uicheckbox(app.RightPanel);
            app.ColorCheckBox.Text = 'Color Images/Videos';
            app.BWCheckBox = uicheckbox(app.RightPanel);
            app.BWCheckBox.Text = 'B&W Images/Videos for single channel data';
            app.BWButtonGroup = uibuttongroup(app.RightPanel);
            app.BWButtonGroup.Title = 'B&W';
            app.Ch1_BW = uiradiobutton(app.BWButtonGroup);
            app.Ch1_BW.Text = '1 [MA]';
            app.Ch1_BW.Value = true;
            app.Ch2_BW = uiradiobutton(app.BWButtonGroup);
            app.Ch2_BW.Text = '2 [GaAsP]';
            app.Ch3_BW = uiradiobutton(app.BWButtonGroup);
            app.Ch3_BW.Text = '3 [GaAsP]';
            app.Ch4_BW = uiradiobutton(app.BWButtonGroup);
            app.Ch4_BW.Text = '4 [MA]';
            app.SaveCreateButton = uibutton(app.RightPanel, 'push');
            app.SaveCreateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCreateButtonPushed, true);
            app.SaveCreateButton.Text = 'Save/Create';
            app.Test = uieditfield(app.RightPanel, 'text');
            app.RawmatfileCheckBox = uicheckbox(app.RightPanel);
            app.RawmatfileCheckBox.Text = 'Raw .mat file';
            app.RawtifffileCheckBox = uicheckbox(app.RightPanel);
            app.RawtifffileCheckBox.Text = 'Raw .tiff file';
            app.VideosImageCheckBox = uicheckbox(app.RightPanel);
            app.VideosImageCheckBox.Text = 'Videos/Image';
            app.SavingoptionsLabel = uilabel(app.RightPanel);
            app.SavingoptionsLabel.Text = 'Saving options';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Process_PrairieData_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end